classdef rosConfigurationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROSConfigurationUIFigure        matlab.ui.Figure
        RoboCupHomeEducationLabel       matlab.ui.control.Label
        ROSConfigurationAppLabel        matlab.ui.control.Label
        LoadPredefinedMappingButtonGroup  matlab.ui.container.ButtonGroup
        TurtleBot2Button                matlab.ui.control.RadioButton
        MARRtinoButton                  matlab.ui.control.RadioButton
        UITable                         matlab.ui.control.Table
        SaveConfigurationButton         matlab.ui.control.Button
        IPAddressofMATLABHostEditFieldLabel  matlab.ui.control.Label
        IPAddressofMATLABHostEditField  matlab.ui.control.EditField
        IPAddressofROSMasterEditFieldLabel  matlab.ui.control.Label
        IPAddressofROSMasterEditField   matlab.ui.control.EditField
        ConfigurationnameEditFieldLabel  matlab.ui.control.Label
        ConfigurationnameEditField      matlab.ui.control.EditField
    end

    
    properties (Access = private)
        topicMapping;
        matlabIP;
        robotIP;
        configName;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load rosTopicMappings
            app.topicMapping = topicTableTB2;
            app.UITable.Data = app.topicMapping;
            
            app.matlabIP = app.IPAddressofMATLABHostEditField.Value;
            app.robotIP = app.IPAddressofROSMasterEditField.Value;
            app.configName = app.ConfigurationnameEditField.Value;
        end

        % Value changed function: IPAddressofMATLABHostEditField
        function IPAddressofMATLABHostEditFieldValueChanged(app, event)
            app.matlabIP = app.IPAddressofMATLABHostEditField.Value;
        end

        % Value changed function: IPAddressofROSMasterEditField
        function IPAddressofROSMasterEditFieldValueChanged(app, event)
            app.robotIP = app.IPAddressofROSMasterEditField.Value;
        end

        % Selection changed function: 
        % LoadPredefinedMappingButtonGroup
        function LoadPredefinedMappingButtonGroupSelectionChanged(app, event)
            selectedButton = app.LoadPredefinedMappingButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "TurtleBot 2"
                    load rosTopicMappings
                    app.topicMapping = topicTableTB2;
                    app.UITable.Data = app.topicMapping;
                case "MARRtino"
                    load rosTopicMappings
                    app.topicMapping = topicTableMARRtino;
                    app.UITable.Data = app.topicMapping;
            end
        end

        % Button pushed function: SaveConfigurationButton
        function SaveConfigurationButtonPushed(app, event)
            s = struct;
            s.matlabIP = app.matlabIP;
            s.robotIP = app.robotIP;
            topicNames = app.topicMapping.Topic;
            for idx = 1:numel(topicNames)
                s.(topicNames(idx)) = app.topicMapping.Name(idx);
            end
            
            curFolder = pwd;
            saveFolder = fileparts(mfilename('fullpath'));
            cd(saveFolder)
            
            save(app.configName,"-struct","s");
            successStr = ["Configuration file " + app.configName + ".mat successfully saved.";"Then, go to the Utilities\connectToRobot.m file and update your file name."];
            uialert(app.ROSConfigurationUIFigure,successStr,'Save successful','Icon','success');
            
            cd(curFolder)
        end

        % Value changed function: ConfigurationnameEditField
        function ConfigurationnameEditFieldValueChanged(app, event)
            app.configName = app.ConfigurationnameEditField.Value;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            idx = event.Indices;
            app.topicMapping{idx(1),idx(2)} = event.NewData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROSConfigurationUIFigure = uifigure('Visible', 'off');
            app.ROSConfigurationUIFigure.Name = 'ROS Configuration';
            app.RoboCupHomeEducationLabel = uilabel(app.ROSConfigurationUIFigure);
            app.RoboCupHomeEducationLabel.Text = 'RoboCup@Home Education';
            app.ROSConfigurationAppLabel = uilabel(app.ROSConfigurationUIFigure);
            app.ROSConfigurationAppLabel.Text = 'ROS Configuration App';
            app.LoadPredefinedMappingButtonGroup = uibuttongroup(app.ROSConfigurationUIFigure);
            app.LoadPredefinedMappingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LoadPredefinedMappingButtonGroupSelectionChanged, true);
            app.LoadPredefinedMappingButtonGroup.Title = 'Load Predefined Mapping';
            app.TurtleBot2Button = uiradiobutton(app.LoadPredefinedMappingButtonGroup);
            app.TurtleBot2Button.Text = 'TurtleBot 2';
            app.TurtleBot2Button.Value = true;
            app.MARRtinoButton = uiradiobutton(app.LoadPredefinedMappingButtonGroup);
            app.MARRtinoButton.Text = 'MARRtino';
            app.UITable = uitable(app.ROSConfigurationUIFigure);
            app.UITable.ColumnName = {'Topic'; 'Description'; 'Name'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false false false];
            app.UITable.ColumnEditable = [false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SaveConfigurationButton = uibutton(app.ROSConfigurationUIFigure, 'push');
            app.SaveConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationButtonPushed, true);
            app.SaveConfigurationButton.Text = 'Save Configuration';
            app.IPAddressofMATLABHostEditFieldLabel = uilabel(app.ROSConfigurationUIFigure);
            app.IPAddressofMATLABHostEditFieldLabel.Text = 'IP Address of MATLAB Host';
            app.IPAddressofMATLABHostEditField = uieditfield(app.ROSConfigurationUIFigure, 'text');
            app.IPAddressofMATLABHostEditField.ValueChangedFcn = createCallbackFcn(app, @IPAddressofMATLABHostEditFieldValueChanged, true);
            app.IPAddressofROSMasterEditFieldLabel = uilabel(app.ROSConfigurationUIFigure);
            app.IPAddressofROSMasterEditFieldLabel.Text = 'IP Address of ROS Master';
            app.IPAddressofROSMasterEditField = uieditfield(app.ROSConfigurationUIFigure, 'text');
            app.IPAddressofROSMasterEditField.ValueChangedFcn = createCallbackFcn(app, @IPAddressofROSMasterEditFieldValueChanged, true);
            app.ConfigurationnameEditFieldLabel = uilabel(app.ROSConfigurationUIFigure);
            app.ConfigurationnameEditFieldLabel.Text = 'Configuration name';
            app.ConfigurationnameEditField = uieditfield(app.ROSConfigurationUIFigure, 'text');
            app.ConfigurationnameEditField.ValueChangedFcn = createCallbackFcn(app, @ConfigurationnameEditFieldValueChanged, true);
            app.ConfigurationnameEditField.Value = 'myTopicConfig';
            % Show the figure after all components are created
            app.ROSConfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rosConfigurationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROSConfigurationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROSConfigurationUIFigure)
        end
    end
end